classdef enclosos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        enclososUIFigure                matlab.ui.Figure
        TextArea                        matlab.ui.control.TextArea
        saveButton                      matlab.ui.control.Button
        outputfilenameEditField         matlab.ui.control.EditField
        outputfilenameEditFieldLabel    matlab.ui.control.Label
        numsamplesEditField             matlab.ui.control.NumericEditField
        numsamplesEditFieldLabel        matlab.ui.control.Label
        computeButton                   matlab.ui.control.Button
        drawpolygonButton               matlab.ui.control.Button
        workspacexminxmaxyminymaxEditField  matlab.ui.control.EditField
        workspacexminxmaxyminymaxLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        SVMTab                          matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        kernelscaleSpinner              matlab.ui.control.Spinner
        kernelscaleSpinnerLabel         matlab.ui.control.Label
        boxconstraintSpinner            matlab.ui.control.Spinner
        boxconstraintSpinnerLabel       matlab.ui.control.Label
        polynomialdegreeSpinner         matlab.ui.control.Spinner
        polynomialdegreeSpinnerLabel    matlab.ui.control.Label
        PSSTab                          matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        polynomialdegreeSpinner_2       matlab.ui.control.Spinner
        polynomialdegreeSpinner_2Label  matlab.ui.control.Label
        EMTab                           matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        deltaSpinner                    matlab.ui.control.Spinner
        deltaSpinnerLabel               matlab.ui.control.Label
        polynomialdegreeSpinner_3       matlab.ui.control.Spinner
        polynomialdegreeSpinner_3Label  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        polygons = {}
        p
    end
    
    methods (Access = private)
        function append_to_textarea(app, text)
            if ~isempty(app.TextArea.Value{1})
                app.TextArea.Value = [app.TextArea.Value(:)', {text}];
            else
                app.TextArea.Value = {text};
            end
            scroll(app.TextArea, 'bottom');
            drawnow;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            hold(app.UIAxes, 'on');
        end

        % Value changed function: workspacexminxmaxyminymaxEditField
        function workspacexminxmaxyminymaxEditFieldValueChanged(app, event)
            value = app.workspacexminxmaxyminymaxEditField.Value;
            tmp_value = eval(value);
            xlim(app.UIAxes, tmp_value(1:2));
            ylim(app.UIAxes, tmp_value(3:4));
        end

        % Button pushed function: drawpolygonButton
        function drawpolygonButtonPushed(app, event)
            app.polygons{end+1} = drawpolygon(app.UIAxes, 'color', 'black', 'FaceAlpha', 0.25);
            app.polygons{end}.Color = 'black';
        end

        % Button pushed function: computeButton
        function computeButtonPushed(app, event)
            dim = 2;
            num_samples = app.numsamplesEditField.Value;
            rng(1, 'twister');
            weight_in_poly = 1;
            weight_not_in_poly = 8;
            include_poly_points = true;

            xlimits = app.UIAxes.XLim;
            ylimits = app.UIAxes.XLim;
            
            app.append_to_textarea('Generate weighted sample points');
            samples = [];
            labels = [];
            weights = [];
            
            samples = rand(num_samples, dim) .* [repmat(xlimits(2) - xlimits(1), num_samples, 1), repmat(ylimits(2) - ylimits(1), num_samples, 1)] + [repmat(xlimits(1), num_samples, 1), repmat(ylimits(1), num_samples, 1)];
            % x = linspace(lowerlimx, upperlimx, 50);
            % y = linspace(lowerlimy, upperlimy, 50);
            % num_samples = 50*50;
            % [X,Y] = meshgrid(x,y);
            % samples = [X(:), Y(:)];
            
            labels = -1 * ones(num_samples, 1);
            weights = weight_in_poly * ones(num_samples, 1);
            cur_poly_points = app.polygons{1}.Position;
            tmp = inpolygon(samples(:, 1), samples(:, 2), cur_poly_points(:, 1), cur_poly_points(:, 2));
            for i = 2:length(app.polygons)
                cur_poly_points = app.polygons{i}.Position;
                tmp = tmp + inpolygon(samples(:, 1), samples(:, 2), cur_poly_points(:, 1), cur_poly_points(:, 2));
            end
            are_in_poly = logical(tmp);
            labels(are_in_poly) = 1;
            % weights(are_in_poly) = weight_in_poly;
            weights(~are_in_poly) = weight_not_in_poly;
            
            % are_in_poly_tmp = logical(inpolygon(samples(:, 1), samples(:, 2), polypoints1(:, 1), polypoints1(:, 2)));
            % weights(are_in_poly_tmp) = 8;
            
            % add obstacle vertices
            if include_poly_points
                for i = 1:length(app.polygons)
                    cur_poly_points = app.polygons{i}.Position;
                    samples = [samples; cur_poly_points];
                    labels = [labels; ones(size(cur_poly_points, 1), 1)];
                    weights = [weights; 2 * ones(size(cur_poly_points, 1), 1)];
                end
            end
            
            scatter(app.UIAxes, samples(:, 1), samples(:, 2), labels+2);

            app.append_to_textarea('Compute p ...');
            approach = app.TabGroup.SelectedTab.Title;
            switch approach
                case 'SVM'
                    if ~exist('svm.m')
                        addpath('svm');
                    end
                    app.p = svm(samples, labels, weights, deg=app.polynomialdegreeSpinner.Value, boxconstraint=app.boxconstraintSpinner.Value, kernelscale=app.kernelscaleSpinner.Value);
                case 'PSS'
                    if ~exist('pss.m')
                        addpath('pss');
                    end
                    app.p = pss(samples(labels == 1, :), deg=app.polynomialdegreeSpinner_2.Value);
                case 'EM'
                    if ~exist('em.m')
                        addpath('em');
                    end
                    app.p = em(samples(labels == 1, :), deg=app.polynomialdegreeSpinner_3.Value, delta=app.deltaSpinner.Value);
            end
            app.append_to_textarea('... done');
            p = app.p;
            app.append_to_textarea(['p(x1, x2) = ' char(p)]);

            fimplicit(app.UIAxes, formula(app.p), [xlimits, ylimits], 'color', 'red', 'linewidth', 2);
            app.append_to_textarea('Plot p==0');
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            filename = [app.outputfilenameEditField.Value '.mat'];
            p = app.p;
            save(filename, 'p', '-mat');
            file = which(filename);
            app.append_to_textarea(['Save p to ' file]);
        end

        % Callback function: not associated with a component
        function enclososUIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.enclososUIFigure = uifigure('Visible', 'off');
            app.enclososUIFigure.Name = 'enclosos';
            app.enclososUIFigure.Icon = fullfile(pathToMLAPP, 'icon.png');
            app.UIAxes = uiaxes(app.enclososUIFigure);
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'y')
            app.TabGroup = uitabgroup(app.enclososUIFigure);
            app.SVMTab = uitab(app.TabGroup);
            app.SVMTab.Title = 'SVM';
            app.GridLayout = uigridlayout(app.SVMTab);
            app.polynomialdegreeSpinnerLabel = uilabel(app.GridLayout);
            app.polynomialdegreeSpinnerLabel.Text = 'polynomial degree';
            app.polynomialdegreeSpinner = uispinner(app.GridLayout);
            app.polynomialdegreeSpinner.Limits = [1 Inf];
            app.polynomialdegreeSpinner.RoundFractionalValues = 'on';
            app.polynomialdegreeSpinner.Value = 4;
            app.boxconstraintSpinnerLabel = uilabel(app.GridLayout);
            app.boxconstraintSpinnerLabel.Text = 'boxconstraint';
            app.boxconstraintSpinner = uispinner(app.GridLayout);
            app.boxconstraintSpinner.Step = 100;
            app.boxconstraintSpinner.Value = 1600;
            app.kernelscaleSpinnerLabel = uilabel(app.GridLayout);
            app.kernelscaleSpinnerLabel.Text = 'kernelscale';
            app.kernelscaleSpinner = uispinner(app.GridLayout);
            app.kernelscaleSpinner.Step = 0.1;
            app.kernelscaleSpinner.Value = 0.48;
            app.PSSTab = uitab(app.TabGroup);
            app.PSSTab.Title = 'PSS';
            app.GridLayout2 = uigridlayout(app.PSSTab);
            app.polynomialdegreeSpinner_2Label = uilabel(app.GridLayout2);
            app.polynomialdegreeSpinner_2Label.Text = 'polynomial degree';
            app.polynomialdegreeSpinner_2 = uispinner(app.GridLayout2);
            app.polynomialdegreeSpinner_2.Limits = [1 Inf];
            app.polynomialdegreeSpinner_2.RoundFractionalValues = 'on';
            app.polynomialdegreeSpinner_2.Value = 4;
            app.EMTab = uitab(app.TabGroup);
            app.EMTab.Title = 'EM';
            app.GridLayout3 = uigridlayout(app.EMTab);
            app.polynomialdegreeSpinner_3Label = uilabel(app.GridLayout3);
            app.polynomialdegreeSpinner_3Label.Text = 'polynomial degree';
            app.polynomialdegreeSpinner_3 = uispinner(app.GridLayout3);
            app.polynomialdegreeSpinner_3.Limits = [1 Inf];
            app.polynomialdegreeSpinner_3.RoundFractionalValues = 'on';
            app.polynomialdegreeSpinner_3.Value = 2;
            app.deltaSpinnerLabel = uilabel(app.GridLayout3);
            app.deltaSpinnerLabel.Text = 'delta';
            app.deltaSpinner = uispinner(app.GridLayout3);
            app.workspacexminxmaxyminymaxLabel = uilabel(app.enclososUIFigure);
            app.workspacexminxmaxyminymaxLabel.Text = {'workspace'; '(xmin, xmax, ymin, ymax)'};
            app.workspacexminxmaxyminymaxEditField = uieditfield(app.enclososUIFigure, 'text');
            app.workspacexminxmaxyminymaxEditField.ValueChangedFcn = createCallbackFcn(app, @workspacexminxmaxyminymaxEditFieldValueChanged, true);
            app.workspacexminxmaxyminymaxEditField.Value = '[0 1 0 1]';
            app.drawpolygonButton = uibutton(app.enclososUIFigure, 'push');
            app.drawpolygonButton.ButtonPushedFcn = createCallbackFcn(app, @drawpolygonButtonPushed, true);
            app.drawpolygonButton.Text = 'draw polygon';
            app.computeButton = uibutton(app.enclososUIFigure, 'push');
            app.computeButton.ButtonPushedFcn = createCallbackFcn(app, @computeButtonPushed, true);
            app.computeButton.Text = 'compute';
            app.numsamplesEditFieldLabel = uilabel(app.enclososUIFigure);
            app.numsamplesEditFieldLabel.Text = 'num samples';
            app.numsamplesEditField = uieditfield(app.enclososUIFigure, 'numeric');
            app.numsamplesEditField.Value = 2000;
            app.outputfilenameEditFieldLabel = uilabel(app.enclososUIFigure);
            app.outputfilenameEditFieldLabel.Text = 'output filename';
            app.outputfilenameEditField = uieditfield(app.enclososUIFigure, 'text');
            app.outputfilenameEditField.Value = 'tmp_poly';
            app.saveButton = uibutton(app.enclososUIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.TextArea = uitextarea(app.enclososUIFigure);
            app.TextArea.Editable = 'off';
            % Show the figure after all components are created
            app.enclososUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = enclosos

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.enclososUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.enclososUIFigure)
        end
    end
end